classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AudiogramPanel            matlab.ui.container.Panel
        KalibracjaPanel           matlab.ui.container.Panel
        PrzeprowadzeniekalibracjiaudiogramuLabel  matlab.ui.control.Label
        KalibrujButton            matlab.ui.control.Button
        CzydwikjestsyszalnyLabel  matlab.ui.control.Label
        TakButton                 matlab.ui.control.Button
        NieButton                 matlab.ui.control.Button
        BadaniePanel              matlab.ui.container.Panel
        CzstotliwociLabel         matlab.ui.control.Label
        DecybeleLabel             matlab.ui.control.Label
        OdtwrzButton              matlab.ui.control.Button
        CzstotliwociListBox       matlab.ui.control.ListBox
        CzstotliwociListBox_2     matlab.ui.control.ListBox
        WybruchaButtonGroup       matlab.ui.container.ButtonGroup
        LeweButton                matlab.ui.control.RadioButton
        PraweButton               matlab.ui.control.RadioButton
        WynikiPanel               matlab.ui.container.Panel
        ObliczButton              matlab.ui.control.Button
        WykresButton              matlab.ui.control.Button
        ZakoczButton              matlab.ui.control.Button
        UbytekwlewymuchuLabel     matlab.ui.control.Label
        UbytekwprawymuchuLabel    matlab.ui.control.Label
        OglnyubytekLabel          matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        dBLabel                   matlab.ui.control.Label
        dBLabel_2                 matlab.ui.control.Label
        dBLabel_3                 matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
        x = 0;
        kanal = 0;
        dB = 0;
        tab_hz = [125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000, 10000];
        tab_db_lewe = [0,0,0,0,0,0,0,0,0,0,0];
        tab_db_prawe = [0,0,0,0,0,0,0,0,0,0,0];
        licznikL = 1;
        licznikP = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KalibrujButton
        function KalibrujButtonPushed(app, event)
            
            fs = 48000;
            f = 1000;
            t = 0:1 / fs:1;
            A=0.0001;
            y = A*sin(2*pi*f*t);
            Py = 1*log10(sum(y.^2));
            coef = 10.^(5/20);
            yy = y.*coef;
            Pyy = 10*log10(sum(yy.^2));
            
            zz = [];
            for i = 1:30
                z1 = y(1, 1:48000);
                z2 = yy(1, 1:48000);
                zz = [zz z1 z2];
                
            end
            
            sound(zz,fs);
            
        end

        % Callback function
        function ZakoczButton_2Pushed(app, event)
            
        end

        % Button pushed function: TakButton
        function TakButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ZakoczButton
        function ZakoczButtonPushed(app, event)
            closereq();
        end

        % Selection changed function: WybruchaButtonGroup
        function WybruchaButtonGroupSelectionChanged(app, event)
            selectedButton = app.WybruchaButtonGroup.SelectedObject;
            if(selectedButton == app.LeweButton)
                app.kanal = 0;
            elseif (selectedButton == app.PraweButton)
                app.kanal = 1;
            end
            
        end

        % Button pushed function: OdtwrzButton
        function OdtwrzButtonPushed(app, event)
            if(app.CzstotliwociListBox.Value == '125  ')
                app.x = 125;
            elseif (app.CzstotliwociListBox.Value == '250  ')
                app.x = 250;
            elseif (app.CzstotliwociListBox.Value == '500  ')
                app.x = 500;
            elseif (app.CzstotliwociListBox.Value == '1000 ')
                app.x = 1000;
            elseif (app.CzstotliwociListBox.Value == '1500 ')
                app.x = 1500;
            elseif (app.CzstotliwociListBox.Value == '2000 ')
                app.x = 2000;
            elseif (app.CzstotliwociListBox.Value == '3000 ')
                app.x = 3000;
            elseif (app.CzstotliwociListBox.Value == '4000 ')
                app.x = 4000;
            elseif (app.CzstotliwociListBox.Value == '6000 ')
                app.x = 6000;
            elseif (app.CzstotliwociListBox.Value == '8000 ')
                app.x = 8000;
            elseif (app.CzstotliwociListBox.Value == '10000')
                app.x = 10000;
            end
            
            if(app.CzstotliwociListBox_2.Value == '0  ')
                app.dB = 0;
            elseif (app.CzstotliwociListBox_2.Value == '10 ')
                app.dB = 10;
            elseif (app.CzstotliwociListBox_2.Value == '20 ')
                app.dB = 20;
            elseif (app.CzstotliwociListBox_2.Value == '30 ')
                app.dB = 30;
            elseif (app.CzstotliwociListBox_2.Value == '40 ')
                app.dB = 40;
            elseif (app.CzstotliwociListBox_2.Value == '50 ')
                app.dB = 50;
            elseif (app.CzstotliwociListBox_2.Value == '60 ')
                app.dB = 60;
            elseif (app.CzstotliwociListBox_2.Value == '70 ')
                app.dB = 70;
            elseif (app.CzstotliwociListBox_2.Value == '80 ')
                app.dB = 80;
            elseif (app.CzstotliwociListBox_2.Value == '90 ')
                app.dB = 90;
            elseif (app.CzstotliwociListBox_2.Value == '100')
                app.dB = 100;
            elseif (app.CzstotliwociListBox_2.Value == '110')
                app.dB = 110;
            elseif (app.CzstotliwociListBox_2.Value == '120')
                app.dB = 120;
            elseif (app.CzstotliwociListBox_2.Value == '130')
                app.dB = 130;
            end
            fs = 48000;
            
            t = 0:1 / fs:1;
            A=0.0001;
            zz=[];
            for i=1:5
                
                z1=t(1,1:48000);
                zz=[zz z1];
            end
            y=A*sin(2*pi*app.x*zz);
            coef = 10.^(app.dB/20);
            yy = y.*coef;
            uchoL = [yy; zeros(size(zz))];
            uchoP = [zeros(size(zz)); yy];
            if(app.kanal == 0)
                sound(uchoL,fs);
            elseif(app.kanal == 1)
                sound(uchoP,fs);
                
            end
            zapytanie = questdlg('      Czy słyszysz dźwięk?','Zapytanie', 'Tak', 'Nie','Tak');
            
            switch zapytanie
                case 'Tak'
                    clear sound;
                    if(app.kanal == 0)
                        app.tab_db_lewe(app.licznikL) = app.dB;
                        app.licznikL = app.licznikL + 1;
                    elseif(app.kanal == 1)
                        app.tab_db_prawe(app.licznikP) = app.dB;
                        app.licznikP = app.licznikP + 1;
                    end
                    
                    
                case 'Nie'
                    clear sound;
            end
        end

        % Value changed function: CzstotliwociListBox_2
        function CzstotliwociListBox_2ValueChanged(app, event)
            value = app.CzstotliwociListBox_2.Value;
            
        end

        % Value changed function: CzstotliwociListBox
        function CzstotliwociListBoxValueChanged(app, event)
            value = app.CzstotliwociListBox.Value;
            
        end

        % Button pushed function: WykresButton
        function WykresButtonPushed(app, event)
            dane = semilogx(app.tab_hz, app.tab_db_lewe, 'red', app.tab_hz, app.tab_db_prawe, 'green', 'Marker','*');
            title('Ubytek słuchu','FontSize',25);
            xlabel('Częstotliwość w Hz','FontSize',20);
            ylabel('Ubytek słuchu w dB','FontSize',20);
            legend('Lewe ucho','Prawe ucho','FontSize',15);
            xticks(app.tab_hz);
            grid on;
        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            if((app.tab_db_lewe(6) - app.tab_db_lewe(3)) > 40)
                wyniklewe = (app.tab_db_lewe(3) + app.tab_db_lewe(4) + app.tab_db_lewe(6) + app.tab_db_lewe(8))/4;
            elseif((app.tab_db_lewe(6) - app.tab_db_lewe(3)) > 40 && app.tab_db_lewe(8) > app.tab_db_lewe(6))
                wyniklewe = (app.tab_db_lewe(3) + app.tab_db_lewe(4) + app.tab_db_lewe(8))/3;
            else
                wyniklewe = (app.tab_db_lewe(3) + app.tab_db_lewe(4) + app.tab_db_lewe(6))/3;
            end
            text = sprintf('%0.1f', wyniklewe);
            app.Label.Text = text;


            if((app.tab_db_prawe(6) - app.tab_db_prawe(3)) > 40)
                wynikprawe = (app.tab_db_prawe(3) + app.tab_db_prawe(4) + app.tab_db_prawe(6) + app.tab_db_prawe(8))/4;
                
            elseif((app.tab_db_prawe(6) - app.tab_db_prawe(3)) > 40 && app.tab_db_prawe(8) > app.tab_db_prawe(6))
                wynikprawe = (app.tab_db_prawe(3) + app.tab_db_prawe(4) + app.tab_db_prawe(8))/3;
            else
                wynikprawe = (app.tab_db_prawe(3) + app.tab_db_prawe(4) + app.tab_db_prawe(6))/3;
            end
            text_2 = sprintf('%0.1f', wynikprawe);
            app.Label_2.Text = text_2;


            if(((wynikprawe - wyniklewe) > 25) || ((wyniklewe - wynikprawe) > 25) )
                if(wyniklewe > wynikprawe)
                    wynikogolny = wynikprawe + 5;
                else
                    wynikogolny = wyniklewe + 5;
                end
            else
                if(wyniklewe > wynikprawe)
                    wynikogolny = wynikprawe;
                else
                    wynikogolny = wyniklewe;
                end
            end
            text_3 = sprintf('%0.1f', wynikogolny);
            app.Label_3.Text = text_3;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AudiogramPanel = uipanel(app.UIFigure);
            app.AudiogramPanel.Title = 'Audiogram';
            app.KalibracjaPanel = uipanel(app.AudiogramPanel);
            app.KalibracjaPanel.Title = 'Kalibracja';
            app.PrzeprowadzeniekalibracjiaudiogramuLabel = uilabel(app.KalibracjaPanel);
            app.PrzeprowadzeniekalibracjiaudiogramuLabel.Text = 'Przeprowadzenie kalibracji audiogramu :';
            app.KalibrujButton = uibutton(app.KalibracjaPanel, 'push');
            app.KalibrujButton.ButtonPushedFcn = createCallbackFcn(app, @KalibrujButtonPushed, true);
            app.KalibrujButton.Text = 'Kalibruj';
            app.CzydwikjestsyszalnyLabel = uilabel(app.KalibracjaPanel);
            app.CzydwikjestsyszalnyLabel.Text = 'Czy dźwięk jest słyszalny?';
            app.TakButton = uibutton(app.KalibracjaPanel, 'push');
            app.TakButton.ButtonPushedFcn = createCallbackFcn(app, @TakButtonPushed, true);
            app.TakButton.Text = 'Tak';
            app.NieButton = uibutton(app.KalibracjaPanel, 'push');
            app.NieButton.Text = 'Nie';
            app.BadaniePanel = uipanel(app.AudiogramPanel);
            app.BadaniePanel.Title = 'Badanie';
            app.CzstotliwociLabel = uilabel(app.BadaniePanel);
            app.CzstotliwociLabel.Text = 'Częstotliwości:';
            app.DecybeleLabel = uilabel(app.BadaniePanel);
            app.DecybeleLabel.Text = 'Decybele:';
            app.OdtwrzButton = uibutton(app.BadaniePanel, 'push');
            app.OdtwrzButton.ButtonPushedFcn = createCallbackFcn(app, @OdtwrzButtonPushed, true);
            app.OdtwrzButton.Text = 'Odtwórz';
            app.CzstotliwociListBox = uilistbox(app.BadaniePanel);
            app.CzstotliwociListBox.Items = {'125  ', '250  ', '500  ', '1000 ', '1500 ', '2000 ', '3000 ', '4000 ', '6000 ', '8000 ', '10000'};
            app.CzstotliwociListBox.ValueChangedFcn = createCallbackFcn(app, @CzstotliwociListBoxValueChanged, true);
            app.CzstotliwociListBox.Value = '125  ';
            app.CzstotliwociListBox_2 = uilistbox(app.BadaniePanel);
            app.CzstotliwociListBox_2.Items = {'0  ', '10 ', '20 ', '30 ', '40 ', '50 ', '60 ', '70 ', '80 ', '90 ', '100', '110', '120', '130'};
            app.CzstotliwociListBox_2.ValueChangedFcn = createCallbackFcn(app, @CzstotliwociListBox_2ValueChanged, true);
            app.CzstotliwociListBox_2.Value = '0  ';
            app.WybruchaButtonGroup = uibuttongroup(app.BadaniePanel);
            app.WybruchaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WybruchaButtonGroupSelectionChanged, true);
            app.WybruchaButtonGroup.Title = 'Wybór ucha:';
            app.LeweButton = uiradiobutton(app.WybruchaButtonGroup);
            app.LeweButton.Text = 'Lewe';
            app.LeweButton.Value = true;
            app.PraweButton = uiradiobutton(app.WybruchaButtonGroup);
            app.PraweButton.Text = 'Prawe';
            app.WynikiPanel = uipanel(app.AudiogramPanel);
            app.WynikiPanel.Title = 'Wyniki';
            app.ObliczButton = uibutton(app.WynikiPanel, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.WykresButton = uibutton(app.WynikiPanel, 'push');
            app.WykresButton.ButtonPushedFcn = createCallbackFcn(app, @WykresButtonPushed, true);
            app.WykresButton.Text = 'Wykres';
            app.ZakoczButton = uibutton(app.WynikiPanel, 'push');
            app.ZakoczButton.ButtonPushedFcn = createCallbackFcn(app, @ZakoczButtonPushed, true);
            app.ZakoczButton.Text = 'Zakończ';
            app.UbytekwlewymuchuLabel = uilabel(app.WynikiPanel);
            app.UbytekwlewymuchuLabel.Text = 'Ubytek w lewym uchu:';
            app.UbytekwprawymuchuLabel = uilabel(app.WynikiPanel);
            app.UbytekwprawymuchuLabel.Text = 'Ubytek w prawym uchu:';
            app.OglnyubytekLabel = uilabel(app.WynikiPanel);
            app.OglnyubytekLabel.Text = 'Ogólny ubytek:';
            app.Label = uilabel(app.WynikiPanel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.WynikiPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.WynikiPanel);
            app.Label_3.Text = '';
            app.dBLabel = uilabel(app.WynikiPanel);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.WynikiPanel);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.WynikiPanel);
            app.dBLabel_3.Text = 'dB';
            app.Image = uiimage(app.AudiogramPanel);
            app.Image.ImageSource = 'tabela.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end